classdef Echo_IMPC_reporter_19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HomeTab                   matlab.ui.container.Tab
        EditcellsSwitch           matlab.ui.control.ToggleSwitch
        EditcellsSwitchLabel      matlab.ui.control.Label
        CheckBox_2                matlab.ui.control.CheckBox
        ApplytoLabel              matlab.ui.control.Label
        entirerowLabel            matlab.ui.control.Label
        emptycellsonlyLabel       matlab.ui.control.Label
        AllowforLabel             matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        InserterrorcodemanuallyDropDown  matlab.ui.control.DropDown
        InserterrorcodemanuallyDropDownLabel  matlab.ui.control.Label
        SelectmetadatalinkmanuallyDropDown  matlab.ui.control.DropDown
        SelectmetadatalinkmanuallyDropDownLabel  matlab.ui.control.Label
        ChooseManuallyDatePicker  matlab.ui.control.DatePicker
        ChoosecohortdatemanuallyDatePickerLabel  matlab.ui.control.Label
        ThisbuttonwillemptythecontentofAnimalsandDatapanelsLabel  matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        ReportPanel               matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        AnimalsPanel              matlab.ui.container.Panel
        PasteFromTeslaTextArea    matlab.ui.control.TextArea
        LoadButton                matlab.ui.control.Button
        PasteButton               matlab.ui.control.Button
        TeslaButton               matlab.ui.control.Button
        GotoTeslafilterandcopythecohortthereandPastehereOrLabel  matlab.ui.control.Label
        NoanimalsLabel            matlab.ui.control.Label
        SavereportButton          matlab.ui.control.Button
        DataPanel                 matlab.ui.container.Panel
        NofileLabel               matlab.ui.control.Label
        OpenreportcsvButton       matlab.ui.control.Button
        SettingsTab               matlab.ui.container.Tab
        ReporttemplatePanel       matlab.ui.container.Panel
        NotemplateselectedLabel   matlab.ui.control.Label
        SelecttemplateButton      matlab.ui.control.Button
        TextArea_4                matlab.ui.control.TextArea
        HelpTab                   matlab.ui.container.Tab
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        dataTxt % echo data from report.csv
        dataTesla % animal list and info from Tesla
%         date % date of measurement
        reportTemplate % cell array containing report template (loaded by startupFcn or user)
        defReportTemplate % default file name and path, used by startupFcn
        dataReport % data to be reported, they will be also shown in UITable
        defMetadataSelection % which value from the drop-down menu of metadata should be default
        defDataTxtFolder % default place to open when looking for results.csv (should be modifiable in future versions)
        defReportPath % path to save report, is set to result.csv path upon opening data
        styles % structure with table cell styles (empty with red bckg, gray for dead mouse IMPC code, etc.)
    end
    
    methods (Access = private)
        
        function redraw(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
        
        function say(app,text,emotion)
%             text of the message and emotion (Err,OK)
            switch emotion
                case 'Err'
                    icon = 'error';
                    title = 'Error';
                case 'celledit'
                    icon = 'info';
                    title = 'Change data?';
                case 'info'
                    icon = 'info';
                    title = 'Info';
                case 'OK'
                    icon = 'success';
                    title = 'OK';
                otherwise
                    icon = 'none';
                    title = '';
            end
            uialert(app.UIFigure,text,title,"Icon",icon);
        end
        
        function generateReport(app)
            
                
%             will check if all necessary inputs are available and process
%             them in order: report template, animals, data from txt file,
%             metadata. If all seems fine, will generate report.

%%%%%%%%%%%%% 1. Template %%%%%%%%%%%%% 
                if isempty(app.reportTemplate)
                    return
                end
%                 removeStyle(app.UITable); %reset all added styles to default
                wb = uiprogressdlg(app.UIFigure,"Value",0,"Cancelable","on","CancelText","Cancel",...
                    "Message","Processing...");
                if wb.CancelRequested == 1
                    close(wb);
                    clearInputs(app,'template');
                    return
                end
                try
    %                 add metadata options to dropdown menu on Home tab
                    metadata = app.reportTemplate{2};
                    col = ismember(metadata(1,:),'True Name');               
                    names = metadata(3:end,col);
                    col = ismember(metadata(1,:),'Metadata link number');  
                    links = metadata(3:end,col);
                    options = join([names,links],', ');
                    app.SelectmetadatalinkmanuallyDropDown.Items = options;
%                     check for default selection
                    if isempty(app.defMetadataSelection)
                        col = ismember(metadata(1,:),'Default (*)');
                        row = find(ismember(metadata(3:end,col),{'*'}));
                        row = row(end);
                        app.SelectmetadatalinkmanuallyDropDown.Value = options(row);
                    else
                        app.SelectmetadatalinkmanuallyDropDown.Value = options(app.defMetadataSelection);
                    end
                    app.SelectmetadatalinkmanuallyDropDown.FontColor = [0 0 0];
                    
%                     add error codes to dropdown menu on Home tab
                    settings = app.reportTemplate{3};
                    col = ismember(settings(1,:),'Error codes');
                    errcodes = settings(2:end,col);
                    errcodes = [errcodes;{''}];
                    app.InserterrorcodemanuallyDropDown.Items = errcodes;
                    app.InserterrorcodemanuallyDropDown.Value = errcodes(1);
                    app.InserterrorcodemanuallyDropDown.FontColor = [0 0 0];
                    app.InserterrorcodemanuallyDropDownLabel.FontColor = [0 0 0];
                    
    %                 fill in report header
                    data = app.reportTemplate{1};
                    header = data(2,:);
                    app.UITable.ColumnName = header;
    %                 and create (empty) report data cell array
                    app.dataReport = cell(0,numel(header));
                    wb.Value = 0.3;
                    if wb.CancelRequested == 1
                        close(wb);
                        clearInputs(app,'template');
                        return
                    end
                catch ME
                    close(wb);
                    clearInputs(app,'template');
                    say(app,{'Template processing failed.',ME.identifier},'Err');
                    return
                end

%%%%%%%%%%%%% 2. Animals %%%%%%%%%%%%%            
                if isempty(app.dataTesla)
                    close(wb);
                    clearInputs(app,'animals');
                    return
                end
                try
                                
    %                 feed report data and UITable with data from tesla
                    app.dataReport = cell(size(app.dataTesla.value,1),size(app.dataReport,2));
                    reportTemplate = app.reportTemplate{1};
                    
                    idxOUT = find(ismember(reportTemplate(5,:),'FROMTESLA'));
                    for i = idxOUT
                        idxIN = ismember(app.dataTesla.header,reportTemplate{4,i});
                        app.dataReport(1:end,i) = app.dataTesla.value(:,idxIN);
                    end
                    app.UITable.Data = app.dataReport;
                    app.NoanimalsLabel.Text = ['Rows of data entered: ',num2str(size(app.dataReport,1))];
                    app.NoanimalsLabel.FontColor = [0 0 0];
                    
                    wb.Value = 0.4;
                    if wb.CancelRequested == 1
                        clearInputs(app,'animals');
                        close(wb);
                        return
                    end
                catch ME
                    close(wb);
                    clearInputs(app,'animals');                    
                    say(app,{'Animal list processing failed.',ME.identifier},'Err');
                    return
                end
                
%%%%%%%%%%%%% 3. Data %%%%%%%%%%%%%         
                if isempty(app.dataTxt)
                    close(wb);
                    clearInputs(app,'data');
                    return
                end
                try      
                    reportTemplate = app.reportTemplate{1};
                    animalIDs = app.dataReport(:,1);
                    dataTxt = app.dataTxt;
                    
%                     loop through all animalIDs
                    for a = 1:size(animalIDs,1)
                        animal = animalIDs{a};

%                         find all rows in dataTxt with this animal
                        dataTxtRows = dataTxt(contains(dataTxt(:,1),animal),:);
                        if isempty(dataTxtRows)
                            dataTxtRows = cell(1,size(dataTxtRows,2));
                        end

%                         loop through all report columns that should be
%                         calculated for this animal
                        for c = find(~ismember(reportTemplate(5,:),'FROMTESLA'))
                            
%                             find action to be done over the data
                            action = reportTemplate{5,c};
                            
%                             split further processing into two branches -
%                             parameters created from within the app and
%                             parameters calculated from dataTxt
                            if isequal(action, 'FROMAPP') %special cases
                                header = reportTemplate{2,c};
                                switch header
                                    case 'Metadata link number'
                                        metadataRowIdx = find(ismember(app.SelectmetadatalinkmanuallyDropDown.Items,app.SelectmetadatalinkmanuallyDropDown.Value));
                                        metadata = app.reportTemplate{2};
                                        res = metadata(metadataRowIdx + 2,1);

                                    otherwise
                                        res = {''};                                        
                                end

                            else % other actions over data

                                if isempty(dataTxtRows)                                                                      
                                        res = {''};
                                else
                                    switch action
                                        case 'FIND'
                                            searchCell = reportTemplate(6:23,c);
                                            res = {getRowData3(app,dataTxtRows,searchCell)};
                                        case 'DATE'
                                            % same as FIND but on top of
                                            % finding a value it must treat
                                            % its conversion to correct
                                            % format
                                            searchCell = reportTemplate(6:23,c); 
                                            dateIN = getRowData3(app,dataTxtRows,searchCell);
                                            typeOf = reportTemplate(24:26,c);
                                            typeOf = strjoin(typeOf,';');
                                            res = {processDate(app,dateIN,typeOf)};
                                        otherwise
                                        res = {''};
                                    end
                                end
                            end                     
                            app.dataReport(a,c) = res;
                        end
                    end
                    app.UITable.Data = app.dataReport;
                    
                    wb.Value = 0.9;
                    if wb.CancelRequested == 1
                        close(wb);
                        clearInputs(app,'data');
                        return
                    end

                catch ME
                    close(wb);
                    clearInputs(app,'data');
                    ME.message
                    say(app,{'Echo data processing failed.',ME.identifier},'Err');
                    return
                end         
                
%%%%%%%%%%%%% 4. Report check %%%%%%%%%%%%%            
                close(wb)
                reportQC(app);

        end
        
        function clearInputs(app,whichImput)
%             will clear requested data inputs: empty data variables and
%             reset labels to 'No data', 'No file', etc. Parameter
%             whichImput will define which exactly, or whether 'all'.
            switch whichImput
                case 'template'
%                     this is a serious action which should result in complete
%                     restart of processing (?)
                    app.reportTemplate = [];
                    app.NotemplateselectedLabel.Text = 'No template selected';
                    app.NotemplateselectedLabel.FontColor = [1 0 0];
                    app.SelectmetadatalinkmanuallyDropDown.Items = {'No metadata'};
                    app.SelectmetadatalinkmanuallyDropDown.Value = 'No metadata';
                    app.SelectmetadatalinkmanuallyDropDown.FontColor = [1 0 0];
                    clearInputs(app,'animals');
                    clearInputs(app,'data');
                    clearInputs(app,'report');
                    app.dataReport = [];
                    app.UITable.Data = app.dataReport;
                    app.UITable.ColumnName = {'No report'};                    
                    
                case 'animals'
                    app.dataTesla = [];
                    app.NoanimalsLabel.Text = 'No animals';
                    app.NoanimalsLabel.FontColor = [1 0 0];
                    app.PasteFromTeslaTextArea.Value = '';
                    
                case 'data'
                    app.dataTxt = [];
                    app.NofileLabel.Text = 'No file';
                    app.NofileLabel.FontColor = [1 0 0];
                    
                case 'report'
                    app.dataReport = cell(0,numel(app.UITable.ColumnName));
                    app.UITable.Data = app.dataReport;
            end
        end
        
        
        function result = processTimestamp(app,timestamp,what)
%            example timestamps: Recorded on 2022-03-28 at time 9:34:
%                                Recorded on 2022-03-28 at time 10:06
            switch what
                case 'date'
                    [startIdx,endIdx] = regexp(timestamp,'\d\d\d\d-\d\d-\d\d');
                    date = timestamp(startIdx:endIdx);
                    if ~isempty(date)
                        date = datetime(date,'InputFormat','yyyy-MM-dd','Format','yyyy-MM-dd');
                        date = datestr(date,'yyyy-mm-dd'); % output string but sure of correct format
                    end
                    result = date;
                case 'time'
                    [startIdx,endIdx] = regexp(timestamp,'[ 12]\d:\d\d');
                    time = timestamp(startIdx:endIdx);
                    if ~isempty(time)
                        time = datetime(time,'InputFormat','hh:mm','Format','hh:mm');
                        time = datestr(time,'hh:MM'); % output string but sure of correct format
                    end
                    result = time;
                    
            end
        end
        
        
        function reportQC(app)
%               will check for empty cells, if empty because mouse died
%               before screen, replace by error code
%               perhaps should implement also data range check?
%               maybe also rounding?            
            try
                wb = uiprogressdlg(app.UIFigure,"Value",0,"Cancelable","on","CancelText","Cancel",...
                    "Message","QC check...");
                if wb.CancelRequested
                    close(wb);
                    return
                end
                
                data = app.UITable.Data;
                header = app.UITable.ColumnName;
%                     find latest among cohort dates
                cohortDate = data(:,ismember(header,'Date'));
                cohortDate = cohortDate(~cellfun(@isempty,cohortDate));
                cohortDate = sort(cohortDate);
                if isempty(cohortDate)
                    cohortDate = '';
                    say(app,'Cohort date not found','Err');
                else
                    cohortDate = cohortDate{end};
                    cohortDate = processTimestamp(app,cohortDate,'date'); %just to check correct format
                    if isempty(cohortDate)
                        say(app,'Problems with cohort date format','Err');
                    end
                end
                
                emptyFound = cell(0);% animal IDs with empty cells
                deadFound = cell(0);% animal IDs where empty cells were replaced by IMPC error code
%                     loop through all animals
                for a=1:size(data,1)
                    animal = data(a,1);
                    sacrificedIdx = find(ismember(app.dataTesla.header,'SACRIFICE'));
                    if isempty(sacrificedIdx)
                        sacrificedDate = '';
                    else
                        sacrificedDate = app.dataTesla.value{a,sacrificedIdx(end)};
                        sacrificedDate = processTimestamp(app,sacrificedDate,'date');
                    end                    
                    if isempty(sacrificedDate)
                        say(app,'Problem with date format of sacrifice','Err'); % hope will never happen, input from Tesla should be OK
                        sacrificed = 0;
                    else
                        sorted = sort([{cohortDate},{sacrificedDate}]);
                        sacrificed = isequal(sorted{2},cohortDate);
                    end
                    
                    
%                         loop through all columns
                    for c = 1:size(data,2)
                        if isempty(data{a,c})
                            if sacrificed
                                if isequal(header{c},'Date')
                                    data(a,c) = {cohortDate};
%                                     if ~isempty(cohortDate)
%                                         data(a,c) = {cohortDate};                                   
%                                     else
%                                         data(a,c) = {''};
% %                                         style to empty
% %                                         addStyle(app.UITable,app.styles.empty,'cell',[a,c]);
%                                     end
                                       
                                else
                                    data(a,c) = {'IMPC_PSC_001'};
                                    deadFound = [deadFound;animal];
%                                     addStyle(app.UITable,app.styles.dead,'cell',[a,c]);
                                end
                            else
                                if isequal(header{c},'Date')
                                    if isempty(cohortDate)
                                        data(a,c) = {''};
%                                         addStyle(app.UITable,app.styles.empty,'cell',[a,c]);
                                        emptyFound = [emptyFound;animal];
                                    else
                                        data(a,c) = {cohortDate};
                                    end
                                else
                                    emptyFound = [emptyFound;animal];
%                                     addStyle(app.UITable,app.styles.empty,'cell',[a,c]);
                                end
                            end
                        end
                    end
                    wb.Value =  a/size(data,1);
                    if wb.CancelRequested
                        close(wb);
                        return
                    end
                end
                close(wb);

                app.UITable.Data = data;
                app.dataReport = data;
                colorTable(app);
                
                emotion = 'info';
%                     report animals with empty cells or dead animals
                if ~isempty(deadFound)
                    deadFound = unique(deadFound);
                    messageDead1 = ['Detected and solved ',num2str(numel(deadFound)),' dead animal(s).'];
                    deadFound = join(deadFound,', ');
                    messageDead2 = ['Animal ID: ',deadFound{1,1}];
                    
                else 
                    messageDead1 = '';
                    messageDead2 = '';
                end
                
                emptyFoundN = numel(emptyFound);
                emptyFound = unique(emptyFound);
                if isempty(emptyFound)
                    messageEmpty = '';
                    messageEmpty2 = '';
                else
                    emptyFound = join(emptyFound,', ');
                    messageEmpty = ['Found ',num2str(emptyFoundN),' empty cells'];
                    messageEmpty2 = ['in these animals: ',emptyFound{1,1}];
                    emotion = 'Err';
                end
                
                if ~isempty([messageEmpty,messageDead1])
                    say(app,{messageEmpty;messageEmpty2;' ';messageDead1;messageDead2},emotion);
                end
                
                
            catch ME
                say(app,{'Report QC failed',ME.identifier},'Err');
                return
            end     
        end
        
        function [CSVdelimiter,decSeparator,quotes] = checkForSeparator(app, text)
            a = strfind(text,';');
            b = strfind(text,',');
            
            if numel(a)<numel(b)
                CSVdelimiter = ',';
                decSeparator = '.';
            else
                CSVdelimiter = ';';
                decSeparator = ',';
            end

            quotes = contains(text,['"',CSVdelimiter])||contains(text,[CSVdelimiter,'"']);
        end
        
        function x = getRowData(app,cellIn,identifier,identifierCol,datCol)
            %     Receives a cell array with rows and collumns, finds an identifying
            %     phrase (identifier) in the expected column (identifierCol) and
            %     outputs a piece of data, i.e. cell, contained in the same row and desired column
            %     (datCol)
            idx = contains(cellIn(:,identifierCol),identifier);
            x = cellIn(idx,datCol);
        end
        
        function x = getRowData3(app,data,searchCell)
            if prod(cellfun(@isempty,searchCell)) % in case there is no info in searchCell
                x = '';
                return
            end
            partOfReport = searchCell{1};
            colOut = searchCell{2};
            nth = searchCell{3};
            
%             keep only data of the needed portion (Animal header, Measurement, or so)
            idx = cellfun(@(x) isequal(x,partOfReport),data(:,3));
            data = data(idx,:);
            
%             get search triplets, remove empty cells and check it is
%             multiple of 3
            searchCell = searchCell(4:end);
            emptyIdx = cellfun(@isempty,searchCell);
            searchCell(emptyIdx) = [];
            if mod(numel(searchCell),3) ~= 0
                x = '';
                say(app,'Bad definition of FIND in the template,','Err');
                return
            end
            
%             go through search triplets
            rowIdx = zeros(size(data,1),numel(searchCell)/3);
            for i = 1:3:numel(searchCell)
                if isequal(searchCell{i+2},'Equals') % in case of exact match
                    rowIdx(:,(i+2)/3) = cellfun(@(x) isequal(x,searchCell{i}), data(:,3 + searchCell{i+1}));
                else % in case of 'contains'
                    rowIdx(:,(i+2)/3) = cellfun(@(x) contains(x,searchCell{i}), data(:,3 + searchCell{i+1}));
                end
            end
            rowIdx = prod(rowIdx,2); % now rowIdx contains only indexes of rows 
            % where all search triplets gave a result (logical AND)
            
            if sum(rowIdx) == 0
                x = '';
            else
                x = data{logical(rowIdx),colOut + 3};
            end
            
            if ~iscell(x)
                x = {x};
            end
            x = x{nth};% if multiple values found, only n-th is reported
        end
        
        function x = processDate(app,dateIn,typeOf)
        %     Function will process text in dateIn depending on typeOf.
        %     If typeOf is 'text', it will report as it is with prepending string '$'
        %     to prevent Excel from converting it into a wrong format. If 'auto'
        %     then it will try to detect a separator and order (YYYY MM DD or DD MM
        %     YYYY). If typeOf starts with 'convert' followed by specific formats
        %    (e.g. 'convert,MM.DD.YYYY,YYYY-MM-DD') it will respect
        %     the specified formats.
        
        %     First check for obvious problems
            if isempty(dateIn)
                x = dateIn;
                return
            end
        % process dateIn
            dateInOrig = dateIn;
            typeOf = strrep(typeOf,';;','');
            switch typeOf
                case 'auto'
                    separators = {'.','/','-'};
                    idx = [];
                    i=1;
        %             Try to find standard type of separators
                    while isempty(idx) && i<=numel(separators)
                        idx = strfind(dateIn,separators{i});
                        i=i+1;
                    end
                    
                    if isempty(idx) % standard separators not found
                        say(app,'Date format err.,date reported as $text','Err');
                        x = processDate(app,dateInOrig,'$text');
                    else
                        dateIn(idx) = '.';
                        stringLengths = strlength(split(dateIn,'.'));
                        stringLengths = num2str(stringLengths)';
                        if (numel(stringLengths)==3) && (isequal(regexp(stringLengths,'[1:2][1:2]4'),1))
        %                     Date in format dd.MM.yyyy, however, check that MM<=12
                            xTemp = str2double(split(dateIn,'.'));
                            if xTemp(2) > 12
                                say(app,'Date format err.,date reported as $text','Err');
                                x = processDate(app,dateInOrig,'$text');
                            else
                                x = processDate(app,dateInOrig,'convert;dd.MM.yyyy;yyyy-mm-dd');
                            end
                        else
                            if (numel(stringLengths)==3) && (isequal(regexp(stringLengths,'4[1:2][1:2]'),1))
        %                       Date in format yyyy.MM.dd, however, check that MM<=12
                                xTemp = str2double(split(dateIn,'.'));
                                if xTemp(2) > 12
                                    say(app,'Date format err.,date reported as $text','Err');
                                x = processDate(app,dateInOrig,'$text');
                                else
                                    x = processDate(app,dateInOrig,'convert;yyyy.MM.dd;yyyy-mm-dd');
                                end
                            else
        %                         Date format unknown
                                say(app,'Date format err.,date reported as $text','Err');
                                x = processDate(app,dateInOrig,'$text');
                            end
                        end
                    end
                    
                case '$text'
                    x = ['$',dateIn];
                otherwise % Should be useful namely for US format (MM.dd.yyyy).
        %             The typeOf must be composed of the key word "convert"
        %             followed by input and output date format, separated by semicolons. 
        %             The function will
        %             take care about correct case of months, since there is an
        %             inconsistency in the Matlab datetime and datestr functions.
                    if contains(typeOf,'convert')
                        typeOf = strrep(typeOf,'Y','y');
                        typeOf = strrep(typeOf,'D','d');
                        typeOf = split(typeOf,';');
                        typeOf(2) = strrep(typeOf(2),'m','M');
                        typeOf(3) = strrep(typeOf(3),'M','m');
                        dateOut = datetime(dateIn,'InputFormat',typeOf{2});
                        x = datestr(dateOut,typeOf{3});
                    else
                        say(app,'Date format err.,date reported as $text','Err');
                        x = processDate(app,dateInOrig,'$text');
                    end
            end
        end
        
        function colorTable(app)
            removeStyle(app.UITable); %reset all added styles to default
            
%             color error code cells
            errcode = cellfun(@(x) contains(x,'IMPC_'),app.UITable.Data);
            [r,c]=find(errcode);
            addStyle(app.UITable,app.styles.errorCode,'cell',[r,c]);
            
%             color empty cells
            empty = cellfun(@isempty,app.UITable.Data);
            [r,c]=find(empty);
            addStyle(app.UITable,app.styles.empty,'cell',[r,c]);
            
%             find some way how to color out-of-range values
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
%             Load default report template.
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%% Comment this part when testing, uncomment otherwise %%%%
            app.defReportTemplate = [{'IMPC_Echo_Template.xlsx'},...
{'C:\Program Files\Echo_IMPC_reporter\application\'}];
            SelecttemplateButtonPushed(app,'default');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.defMetadataSelection = [];
            app.defDataTxtFolder = {'\\primus.img.cas.cz\data\83_IMPC_DATA\Cardiovascular\Echo\',...
                '\\primus.img.local\data\83_IMPC_DATA\Cardiovascular\Echo\'};
            app.styles.empty = uistyle("BackgroundColor",[1,0.1,0.1]);
            app.styles.dead = uistyle("BackgroundColor",[0.65,0.65,0.65]);
            app.styles.errorCode = uistyle("BackgroundColor",[0.65,0.65,0.65]);
            app.styles.white = uistyle("BackgroundColor",[1 1 1]);
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
%%%%%%%%%%%% Only for testing purposes. Comment otherwise %%%%%%
%             temp = app.PasteFromTeslaTextArea.Value;
%             app.defReportTemplate = [{'IMPC_Echo_Template.xlsx'},...
%                 {'D:\05-Jiri scripts and apps\ECG-Echo-skripty\Echo_IMPC_reporter\'}];
%             SelecttemplateButtonPushed(app,'default');
%             app.PasteFromTeslaTextArea.Value = temp;
%             PasteFromTeslaTextAreaValueChanged(app,'');
%             OpenreportcsvButtonPushed(app,'testing');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clearInputs(app,'animals');
            clearInputs(app,'data');
            clearInputs(app,'report');
            app.ChooseManuallyDatePicker.Value = NaT;
        end

        % Button pushed function: TeslaButton
        function TeslaButtonPushed(app, event)
            web('https://tesla.img.cas.cz/shiny/ccp/AnimalList');
        end

        % Button pushed function: SelecttemplateButton
        function SelecttemplateButtonPushed(app, event)
            try
                if isequal(event,'default')
                    file = app.defReportTemplate{1};
                    path = app.defReportTemplate{2};
                else
                    [file,path] = uigetfile([app.defReportTemplate{2},'*.xlsx'],'Select template file',"MultiSelect","off");
                    redraw(app);
                end
                
                if isequal(file,0)
                    return
                end
                
                [status,sheets] = xlsfinfo([path,file]);
                if isempty(status)
                    say(app,'Incorrect format (must be xlsx)','Err');
                    return
                end

    %             check number of sheets (must be at least 3) and read first
    %             three as data metadata and settings
                if numel(sheets)<3
                    say(app,'Incorrect format (must have min. 3 sheets)','Err');
                    return
                end
                for i = 1:3
                    [~,~,app.reportTemplate{i}] = xlsread([path,file],sheets{i});
%                     replace NaN with empty char
                    temp = app.reportTemplate{i};
                    for ii=1:size(temp,1)
                        for jj=1:size(temp,2)
                            if isnan(temp{ii,jj})
                                temp{ii,jj} = '';
                            end
                        end
                    end
                    app.reportTemplate{i} = temp;
                end
                app.NotemplateselectedLabel.Text = [path,file];
                app.NotemplateselectedLabel.FontColor = [0 0 0];
                app.defMetadataSelection = [];
                
                generateReport(app);

            catch ME
                clearInputs(app,'template');
                say(app,ME.message,'Err');
            end
            
        end

        % Value changed function: PasteFromTeslaTextArea
        function PasteFromTeslaTextAreaValueChanged(app, event)

            try
                value = app.PasteFromTeslaTextArea.Value;
%                 preliminary check if at least number of rows is accurate,
%                 minimal is 3 rows of headers and 1 row of animal info
                if size(value,1) < 4
                    clearInputs(app,'animals');
                    clearInputs(app,'report');
                    say(app,'Invalid animal list','Err');
                    return
                end
%                 remove empty rows exept for inside header
                NonEmptyIdx = ~cellfun(@isempty,value(4:end));
                NonEmptyIdx = [1;1;1;NonEmptyIdx];
                value = value(logical(NonEmptyIdx));
                r = size(value,1) - 2;
                c = numel(strsplit(value{3,1},'\t'));
                
                dataTesla = cell(r,c);
                for i=3:size(value,1)
                    row = strsplit(value{i,1},'\t','CollapseDelimiters',false);
                    dataTesla(i-2,1:numel(row)) = row;
                end
                app.dataTesla.header = dataTesla(1,:);
                app.dataTesla.value = dataTesla(2:end,:);
                generateReport(app);
                
            catch ME
                clearInputs(app,'animals');
                say(app,ME.message,'Err');
            end
            
        end

        % Button pushed function: OpenreportcsvButton
        function OpenreportcsvButtonPushed(app, event)
            try
                if isequal(event,'testing') % only for testing purposes
                    file = 'Example report c233.csv';
                    path = 'D:\05-Jiri scripts and apps\ECG-Echo-skripty\Echo_IMPC_reporter\';
                else
%                 check for source path accessibility
                defPath = getenv('USERPROFILE');
                for i=1:numel(app.defDataTxtFolder)
                    if exist(app.defDataTxtFolder{i},'dir')
                        defPath = app.defDataTxtFolder{i};
                        break
                    end
                end
               
                    [file,path] = uigetfile([defPath,'*.csv'],'Select csv file with result of echo analysis',"MultiSelect","off");
                    redraw(app);
                end
                if isequal(file,0)
                    return
                end

                app.defReportPath = path;
                app.defDataTxtFolder = {path};
                
%               Try to auto-detect csv separator and decimal delimiter
                [fid,errmsg] = fopen([path,file]);
                if ~isempty(errmsg)
                    clearInputs(app,'data'); 
                    say(app,errmsg,'Err');
                    fclose(fid);
                    return
                end
                dataIn = textscan(fid,'%c');
                fclose(fid);
                dataIn = dataIn{1}';
                [CSVdelimiter,decSeparator,quotes] = checkForSeparator(app,dataIn);
                if isempty(CSVdelimiter)
                    clearInputs(app,'data'); 
                    say(app,'Unable to identify CSV separator','Err');
                    fclose(fid);
                    return
                end
%                 Now import the data again with delimiters
%                 at the end of line
                fid = fopen([path,file]);
                dataIn = textscan(fid,'%s','delimiter',{'\r\n'});
                fclose(fid);
                dataIn = dataIn{1};

        %         Create new cell array of data and fill in correct values, number
        %         of columns will adapt automatically during processing
                data = cell(numel(dataIn),1);
        
        %         Go through all text lines, correct decimal separator, remove
        %         quotes and split into columns according to CSV delimiter                
                
                for j = 1:size(data,1)
                    line = dataIn{j,:};
                    if quotes % in case double quotes are used together with delimiter
                        line = strrep(line,[CSVdelimiter,'s"'],'$TEMPORLIMIT$s');
                        line = strrep(line,[CSVdelimiter,'d"'],'$TEMPORLIMIT$d');
                        line = strrep(line,'"','');
                    end
                    if decSeparator == ','
                        idx = regexp(line,'[0-9],[0-9]');
                        idx = idx + 1;
                        line(idx) = '.';
                        line = strrep(line,',',' ');
                        line = strrep(line,CSVdelimiter,',');
                    end           
                    if ~isempty(line) && isequal(line(end),' ') %remove empty char at the end
                        line(end) = '';
                    end
                    line = strrep(line,'$TEMPORLIMIT$s',';s');
                    line = strrep(line,'$TEMPORLIMIT$d',';d');           
                    line = split(line,',');
                    data(j,1:numel(line)) = line';
                end
                
            %       Prepend three columns to data: 
            %       Animal ID, Measurement Name and Report Subpart 
                    data = [cell(size(data,1),3),data];
                    
            %        Identify typical parts of the file
            
            %        Find the file header
                    idxOfAnimalStart = find(contains(data(:,4),'Series Name'));
                    if isempty(idxOfAnimalStart)
                        clearInputs(app,'data');
                        say(app,'No animal found in the data','Err');
                        return
                    end      
                    idxOfFileHeader = 1:idxOfAnimalStart(1)-1;
                    data(idxOfFileHeader,3) = {'File Header'};
            %         Find individual animals and collect their IDs
                    foundIDs = {};        
                    idxOfAnimalStart = [idxOfAnimalStart;size(data,1)+1];
            %        Through all animals        
                    for j = 1:numel(idxOfAnimalStart)-1
            %             Get the piece of text with animal header, measurements and
            %             calcullations
                        foundAnimal = data(idxOfAnimalStart(j):idxOfAnimalStart(j+1)-1,:);
            %             Get animal ID
                        foundIDs(j,1) = getRowData(app,foundAnimal,'Animal ID',4,5);
                        data(idxOfAnimalStart(j):idxOfAnimalStart(j+1)-1,1) = foundIDs(j,1);
            %             Get starting rows of measurements (measurement files)
                        idxOfMeasurementStart = find(contains(foundAnimal(:,4),'Measurement File'));
                        if isempty(idxOfMeasurementStart)
%                             say(app,['No measurement found in animal ',foundIDs{j,1}],'warning');
                            data(idxOfAnimalStart(j):idxOfAnimalStart(j+1)-1,3) = {'Animal Header'};
                            continue
                        end
                        measurementFile = foundAnimal(idxOfMeasurementStart,5);
            
            %             Set animal header
                        data(idxOfAnimalStart(j):idxOfMeasurementStart(1)...
                            + idxOfAnimalStart(j)-2,3) = {'Animal Header'};
            %             Set measurement file
                        idxOfMeasurementStart = [idxOfMeasurementStart;size(foundAnimal,1)+1];          
                        for k = 1:numel(idxOfMeasurementStart)-1
                            k1 = idxOfMeasurementStart(k) + idxOfAnimalStart(j) - 1;
                            k2 = idxOfMeasurementStart(k+1) + idxOfAnimalStart(j) - 2;
                            data(k1:k2,2) = measurementFile(k);
            %                 Set sub-part of measurement (Header, Measurement,
            %                 Calculation)
                            temp = {};
                            for l=k1:k2
                                query = data{l,4};
                                switch query
                                    case 'Measurement File'
                                        temp = {'Measurement Header'};
                                    case 'Measurement'
                                        temp = {'Measurement'};
                                    case 'Calculation'
                                        temp = {'Calculation'};       
                                end
                                data(l,3) = temp;
                            end
                        end
                    end
                    data(cellfun(@isempty,data)) = {''};
                    %         Animal IDs are in the first column of all rows except for those of
        %                     the file header. Add all IDs there now.
                    data(idxOfFileHeader,1) = {strjoin(unique(foundIDs))};
                    if numel(foundIDs) ~= numel(unique(foundIDs))
                        say(app,'Duplicate animal IDs in data!','Err');                       
                    end
            
                app.dataTxt = data;
                       
                app.NofileLabel.Text = [path,file];
                app.NofileLabel.FontColor = [0 0 0];
                generateReport(app);
            catch ME
                clearInputs(app,'data'); 
                say(app,ME.message,'Err');
            end
                      
        end

        % Value changed function: SelectmetadatalinkmanuallyDropDown
        function SelectmetadatalinkmanuallyDropDownValueChanged(app, event)
            value = app.SelectmetadatalinkmanuallyDropDown.Value;
%             clearInputs(app,'report');
            app.defMetadataSelection = find(ismember(app.SelectmetadatalinkmanuallyDropDown.Items,value));
            app.ChooseManuallyDatePicker.Value = NaT;
%             generateReport(app);

%             find column in data and just replace what is there
            col = find(ismember(app.UITable.ColumnName,'Metadata link number'));
            if isempty(col)
                say(app,'The column "Metadata link number" not found in the table','info');
            elseif isempty(app.UITable.Data)
                say(app,'No animals in the table yet.','info');
            else
                metadataRowIdx = find(ismember(app.SelectmetadatalinkmanuallyDropDown.Items,value));
                metadataRowIdx = metadataRowIdx(1);
                metadata = app.reportTemplate{2};
                if isempty(metadata)
                    return
                end
                app.UITable.Data(:,col) = metadata(metadataRowIdx + 2,1);
                colorTable(app);
            end
        end

        % Value changed function: ChooseManuallyDatePicker
        function ChooseManuallyDatePickerValueChanged(app, event)
%             manually rewrite cohort date and redo report QC
            value = app.ChooseManuallyDatePicker.Value;
            
            if isempty(app.UITable.Data)
                return
            end
            stringValue = datestr(value,'yyyy-mm-dd');
            msg = {'Cohort date of all animals will be set to';stringValue};
            title = 'Confirm change';
            selection = uiconfirm(app.UIFigure,msg,title, ...
           'Options',{'I agree','Cancel'}, ...
           'DefaultOption',1,'CancelOption',2);
            
            if isequal(selection,'I agree')
                header = app.UITable.ColumnName;
                cohortDateIdx = ismember(header,'Date');
                app.UITable.Data(:,cohortDateIdx) = {stringValue};
                app.dataReport = app.UITable.Data;
                reportQC(app);
            end
        end

        % Button pushed function: PasteButton
        function PasteButtonPushed(app, event)
            app.PasteFromTeslaTextArea.Value = clipboard('paste');
            PasteFromTeslaTextAreaValueChanged(app, '');
        end

        % Button pushed function: SavereportButton
        function SavereportButtonPushed(app, event)
            if isempty(app.UITable.Data)
                say(app,'Nothing to save','info');
                return
            end
            
            try
                header = app.reportTemplate{1};
                header = header(1:3,:);
                data = app.UITable.Data;
                cohortIDidx = ismember(header(2,:),'Cohort');
                if sum(cohortIDidx)==0
                    cohortID = '';
                else    
                    cohortID = data{1,cohortIDidx}; 
                end
%               Default report name example: IMPC_ECH_001 Echo C233                      
                [file,path] = uiputfile('*.xlsx','Save report as',[app.defReportPath,'IMPC_ECH_001_',cohortID,'.xlsx']);
                redraw(app);
                
                if ~isequal(file,0)
                    data = [header;data];
                    metadata = app.reportTemplate{2};
                    idx = ~cellfun(@isempty,(metadata(2,:)));
                    if exist([path,file],'file')
                        delete([path,file]);
                    end
                    writecell(data,[path,file],'sheet','Data');
                    writecell(metadata(:,idx),[path,file],'sheet','Metadata');
                    say(app,'File saved','OK');
                    winopen(path);
                    winopen([path,file]);
                end
                
            catch ME
                say(app,{'Problems when saving ';ME.identifier},'Err');
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if isequal(app.EditcellsSwitch.Value,'Off')
                return
            end
            indices = event.Indices;            
            indices1 = indices(1);
%             check if entire row should be processed
            if app.CheckBox_2.Value
                indices2 = 2:size(app.UITable.Data,2);
%             batch processing of all cells in a row will ALWAYS be allowed for empty cells only                
                justempty = true;
                app.CheckBox.Value = 1;
            else
                indices2 = indices(2);
                justempty = app.CheckBox.Value;
            end
            
            for i=1:numel(indices2)
                cellempty = isempty(app.UITable.Data{indices1,indices2(i)});
                if justempty && cellempty
                    app.UITable.Data(indices1,indices2(i)) = {app.InserterrorcodemanuallyDropDown.Value};
                elseif ~justempty
                    app.UITable.Data(indices1,indices2(i)) = {app.InserterrorcodemanuallyDropDown.Value};
                end
                
            end
            colorTable(app);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value
                app.CheckBox.Value = 1;
                app.CheckBox.Enable = 'off';
            else
                app.CheckBox.Enable = 'on'
            end
        end

        % Value changed function: EditcellsSwitch
        function EditcellsSwitchValueChanged(app, event)
            value = app.EditcellsSwitch.Value;
            if isequal(value,'On')
                app.UITable.ColumnEditable = true;
                app.InserterrorcodemanuallyDropDown.Enable = true;
                app.InserterrorcodemanuallyDropDownLabel.Enable = true;
                app.AllowforLabel.Enable = true;
                app.emptycellsonlyLabel.Enable = true;
                app.CheckBox.Value = true;
                app.CheckBox.Enable = true;
                app.CheckBox_2.Value = false;
                app.CheckBox_2.Enable = true;
                app.ApplytoLabel.Enable = true;
                app.entirerowLabel.Enable = true;
            else
                app.UITable.ColumnEditable = false;
                app.InserterrorcodemanuallyDropDown.Enable = false;
                app.InserterrorcodemanuallyDropDownLabel.Enable = false;
                app.CheckBox.Enable = false;
                app.CheckBox_2.Enable = false;
                app.AllowforLabel.Enable = false;
                app.emptycellsonlyLabel.Enable = false;
                app.CheckBox_2.Enable = false;
                app.ApplytoLabel.Enable = false;
                app.entirerowLabel.Enable = false;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            colorTable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Echo IMPC Reporter 1.1';
            app.UIFigure.Icon = 'VEVO_IMPC_reporter_icon_02.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.DataPanel = uipanel(app.HomeTab);
            app.DataPanel.Title = 'Data';
            app.OpenreportcsvButton = uibutton(app.DataPanel, 'push');
            app.OpenreportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @OpenreportcsvButtonPushed, true);
            app.OpenreportcsvButton.Text = 'Open report.csv';
            app.NofileLabel = uilabel(app.DataPanel);
            app.NofileLabel.Text = 'No file';
            app.SavereportButton = uibutton(app.HomeTab, 'push');
            app.SavereportButton.ButtonPushedFcn = createCallbackFcn(app, @SavereportButtonPushed, true);
            app.SavereportButton.Text = 'Save report';
            app.AnimalsPanel = uipanel(app.HomeTab);
            app.AnimalsPanel.Title = 'Animals';
            app.NoanimalsLabel = uilabel(app.AnimalsPanel);
            app.NoanimalsLabel.Text = 'No animals';
            app.GotoTeslafilterandcopythecohortthereandPastehereOrLabel = uilabel(app.AnimalsPanel);
            app.GotoTeslafilterandcopythecohortthereandPastehereOrLabel.Text = 'Go to                 , filter and copy the cohort there and                here.                                          Or                         animallist csv (PyRat)';
            app.TeslaButton = uibutton(app.AnimalsPanel, 'push');
            app.TeslaButton.ButtonPushedFcn = createCallbackFcn(app, @TeslaButtonPushed, true);
            app.TeslaButton.Text = 'Tesla';
            app.PasteButton = uibutton(app.AnimalsPanel, 'push');
            app.PasteButton.ButtonPushedFcn = createCallbackFcn(app, @PasteButtonPushed, true);
            app.PasteButton.Text = 'Paste';
            app.LoadButton = uibutton(app.AnimalsPanel, 'push');
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.PasteFromTeslaTextArea = uitextarea(app.AnimalsPanel);
            app.PasteFromTeslaTextArea.ValueChangedFcn = createCallbackFcn(app, @PasteFromTeslaTextAreaValueChanged, true);
            app.PasteFromTeslaTextArea.Value = {'Exported data'; ''; '	ID	LAB_ID	CAGE_ID	STRAIN	SACRIFICE	GENDER	GROUP	COHORT_DATE	COHORT_ID	Sacrifice_state	STATUS CODE	COMMENT	HELP	HELP2'; '484	E64-G5534	1L1R	SE83-39926	Brinp3	2022-04-20	M	M hom Brinp3	2022-02-28	c233				1	1'; '485	E64-G5541	1L3R	SE83-39927	Brinp3	2022-04-20	M	M hom Brinp3	2022-02-28	c233				1	1'; '486	E64-G5557	3L1R	SE83-39930	Brinp3	2022-03-29	F	F hom Brinp3	2022-02-28	c233	Died before uCT			0	1'; '487	E64-G5570	2L3R	SE83-39929	Brinp3	2022-04-20	M	M hom Brinp3	2022-02-28	c233				1	1'; '488	E64-G5573	3L3R	SE83-39929	Brinp3	2022-04-20	M	M hom Brinp3	2022-02-28	c233				1	1'; '489	E64-G5576	1L3R	SE83-39930	Brinp3	2022-04-20	F	F hom Brinp3	2022-02-28	c233				1	1'};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
            app.ReportPanel = uipanel(app.HomeTab);
            app.ReportPanel.Title = 'Report';
            app.UITable = uitable(app.ReportPanel);
            app.UITable.ColumnName = {'No report'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ClearButton = uibutton(app.HomeTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ThisbuttonwillemptythecontentofAnimalsandDatapanelsLabel = uilabel(app.HomeTab);
            app.ThisbuttonwillemptythecontentofAnimalsandDatapanelsLabel.Text = 'This button will empty the content of Animals and Data panels.';
            app.ChoosecohortdatemanuallyDatePickerLabel = uilabel(app.HomeTab);
            app.ChoosecohortdatemanuallyDatePickerLabel.Text = 'Choose cohort date manually';
            app.ChooseManuallyDatePicker = uidatepicker(app.HomeTab);
            app.ChooseManuallyDatePicker.Limits = [datetime([2018 1 1]) datetime([2099 12 31])];
            app.ChooseManuallyDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ChooseManuallyDatePicker.ValueChangedFcn = createCallbackFcn(app, @ChooseManuallyDatePickerValueChanged, true);
            app.ChooseManuallyDatePicker.Placeholder = 'yyyy-mm-dd';
            app.SelectmetadatalinkmanuallyDropDownLabel = uilabel(app.HomeTab);
            app.SelectmetadatalinkmanuallyDropDownLabel.Text = 'Select metadata link manually';
            app.SelectmetadatalinkmanuallyDropDown = uidropdown(app.HomeTab);
            app.SelectmetadatalinkmanuallyDropDown.Items = {'No metadata'};
            app.SelectmetadatalinkmanuallyDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmetadatalinkmanuallyDropDownValueChanged, true);
            app.SelectmetadatalinkmanuallyDropDown.Value = 'No metadata';
            app.InserterrorcodemanuallyDropDownLabel = uilabel(app.HomeTab);
            app.InserterrorcodemanuallyDropDownLabel.Enable = 'off';
            app.InserterrorcodemanuallyDropDownLabel.Text = 'Insert error code manually';
            app.InserterrorcodemanuallyDropDown = uidropdown(app.HomeTab);
            app.InserterrorcodemanuallyDropDown.Items = {'No codes'};
            app.InserterrorcodemanuallyDropDown.Enable = 'off';
            app.InserterrorcodemanuallyDropDown.Value = 'No codes';
            app.CheckBox = uicheckbox(app.HomeTab);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.AllowforLabel = uilabel(app.HomeTab);
            app.AllowforLabel.Enable = 'off';
            app.AllowforLabel.Text = 'Allow for';
            app.emptycellsonlyLabel = uilabel(app.HomeTab);
            app.emptycellsonlyLabel.Enable = 'off';
            app.emptycellsonlyLabel.Text = 'empty cells only';
            app.entirerowLabel = uilabel(app.HomeTab);
            app.entirerowLabel.Enable = 'off';
            app.entirerowLabel.Text = 'entire row';
            app.ApplytoLabel = uilabel(app.HomeTab);
            app.ApplytoLabel.Enable = 'off';
            app.ApplytoLabel.Text = 'Apply to';
            app.CheckBox_2 = uicheckbox(app.HomeTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.EditcellsSwitchLabel = uilabel(app.HomeTab);
            app.EditcellsSwitchLabel.Text = 'Edit cells';
            app.EditcellsSwitch = uiswitch(app.HomeTab, 'toggle');
            app.EditcellsSwitch.ValueChangedFcn = createCallbackFcn(app, @EditcellsSwitchValueChanged, true);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ReporttemplatePanel = uipanel(app.SettingsTab);
            app.ReporttemplatePanel.Title = 'Report template';
            app.TextArea_4 = uitextarea(app.ReporttemplatePanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Template for IMPC report is xlsx file that contains critical information and settings. See Help for details.'; 'Default path to the template is:'; 'C:\Program Files\Echo_IMPC_reporter\application\IMPC_Echo_Template.xlsx'};
            app.SelecttemplateButton = uibutton(app.ReporttemplatePanel, 'push');
            app.SelecttemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttemplateButtonPushed, true);
            app.SelecttemplateButton.Text = 'Select template';
            app.NotemplateselectedLabel = uilabel(app.ReporttemplatePanel);
            app.NotemplateselectedLabel.Text = 'No template selected';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Value = {'Echo_IMPC_reporter app takes animal list from tesla/shiny server and a report from Vevo analysis (.csv) and creates xlsx report file for analysis of echocardiography by Biostatistics Unit. In the future it should be able to use also an animal list from PyRat.'; ''; 'App must be installed to ''C:\Program Files'' where it will create ''ECG_IMPC_reporter'' folder.'; ''; 'HOW TO USE THE APP:'; ''; '1. Open ''https://tesla.img.cas.cz/shiny/ccp/AnimalList/'' and filter the animal list for desired cohort number (e.g.type ''c230'' in COHORT_ID field). You can use ''Tesla'' button that will bring you to this web page.'};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
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Echo_IMPC_reporter_19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end